classdef ulgGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        LoadFilesPanel  matlab.ui.container.Panel
        Load            matlab.ui.control.Button
        PlotsPanel      matlab.ui.container.Panel
        XYZ             matlab.ui.control.Button
        Vel             matlab.ui.control.Button
        Accel           matlab.ui.control.Button
        Alt             matlab.ui.control.Button
        OptionsPanel    matlab.ui.container.Panel
        Exit            matlab.ui.control.Button
        Image           matlab.ui.control.Image
        StatsPanel      matlab.ui.container.Panel
        Max_u           matlab.ui.control.Label
        Max_v           matlab.ui.control.Label
        Max_alt         matlab.ui.control.Label
    end

    
    properties (Access = private)
        ulg_file % Description
        MATname
    end
    
    methods (Access = private)
        
        function fileName = corretName(~,ulg_file)
            % The log file name, no file specifier required
            fileName_check = ulg_file;
            dotLocations = find(fileName_check == '.');
            if isempty(dotLocations)
               	% No dots at all found so just take entire name.
               	fileName = ulg_file;
            else
               	% Take up to , but not including, the first dot.
               	fileName = ulg_file(1:dotLocations(1)-1);
            end
            
        end
        
        function matName = addMATextention(~,ulg_file)
            matName = sprintf('%s.mat',ulg_file);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            app.ulg_file = uigetfile('*.ulg');
            app.ulg_file = corretName(app,app.ulg_file);
            app.MATname = addMATextention(app,app.ulg_file);
            ulg2mat(app.ulg_file);
        end

        % Button pushed function: XYZ
        function XYZButtonPushed(app, event)
            plotXYZ(app.MATname);
        end

        % Button pushed function: Alt
        function AltButtonPushed(app, event)
            plotAlt(app.MATname);
        end

        % Button pushed function: Accel
        function AccelButtonPushed(app, event)
            plotAccel(app.MATname,0.01);
        end

        % Button pushed function: Vel
        function VelButtonPushed(app, event)
            plotVel(app.MATname);
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadFilesPanel = uipanel(app.UIFigure);
            app.LoadFilesPanel.Title = 'Load Files';
            app.Load = uibutton(app.LoadFilesPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load .ulg File';
            app.PlotsPanel = uipanel(app.UIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.XYZ = uibutton(app.PlotsPanel, 'push');
            app.XYZ.ButtonPushedFcn = createCallbackFcn(app, @XYZButtonPushed, true);
            app.XYZ.Text = 'Plot X-Y-Z from GPS';
            app.Vel = uibutton(app.PlotsPanel, 'push');
            app.Vel.ButtonPushedFcn = createCallbackFcn(app, @VelButtonPushed, true);
            app.Vel.Text = 'Plot Velocities from GPS ';
            app.Accel = uibutton(app.PlotsPanel, 'push');
            app.Accel.ButtonPushedFcn = createCallbackFcn(app, @AccelButtonPushed, true);
            app.Accel.Text = 'Plot Acceleration Data ';
            app.Alt = uibutton(app.PlotsPanel, 'push');
            app.Alt.ButtonPushedFcn = createCallbackFcn(app, @AltButtonPushed, true);
            app.Alt.Text = 'Plot Altitude from Baro';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.Exit = uibutton(app.OptionsPanel, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'VGuidance_DocsLogo_BlueFont.jpeg';
            app.StatsPanel = uipanel(app.UIFigure);
            app.StatsPanel.Title = 'Stats';
            app.Max_u = uilabel(app.StatsPanel);
            app.Max_u.Text = '';
            app.Max_v = uilabel(app.StatsPanel);
            app.Max_v.Text = '';
            app.Max_alt = uilabel(app.StatsPanel);
            app.Max_alt.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ulgGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end